classdef create_vXray < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        cb3                       matlab.ui.control.CheckBox
        cb2                       matlab.ui.control.CheckBox
        ed9                       matlab.ui.control.EditField
        actualdegLabel            matlab.ui.control.Label
        btn8                      matlab.ui.control.Button
        btn7                      matlab.ui.control.Button
        ed8                       matlab.ui.control.EditField
        SavefolderEditFieldLabel  matlab.ui.control.Label
        ed7                       matlab.ui.control.EditField
        centerYLabel_3            matlab.ui.control.Label
        ed6                       matlab.ui.control.EditField
        lengthLabel               matlab.ui.control.Label
        ed5                       matlab.ui.control.EditField
        maxEditFieldLabel         matlab.ui.control.Label
        ed4                       matlab.ui.control.EditField
        minEditFieldLabel         matlab.ui.control.Label
        btn6                      matlab.ui.control.Button
        ed3                       matlab.ui.control.EditField
        centerYLabel              matlab.ui.control.Label
        ed2                       matlab.ui.control.EditField
        degEditFieldLabel         matlab.ui.control.Label
        cb1                       matlab.ui.control.CheckBox
        btn5                      matlab.ui.control.Button
        ed1                       matlab.ui.control.EditField
        rangeEditFieldLabel       matlab.ui.control.Label
        btn4                      matlab.ui.control.Button
        btn3                      matlab.ui.control.Button
        btn2                      matlab.ui.control.Button
        sl2                       matlab.ui.control.Slider
        centerYLabel_2            matlab.ui.control.Label
        sl1                       matlab.ui.control.Slider
        rotdegLabel               matlab.ui.control.Label
        lst1                      matlab.ui.control.ListBox
        bg                        matlab.ui.container.ButtonGroup
        rb2                       matlab.ui.control.RadioButton
        rb1                       matlab.ui.control.RadioButton
        txt1                      matlab.ui.control.Label
        btn1                      matlab.ui.control.Button
        ax4                       matlab.ui.control.UIAxes
        ax3                       matlab.ui.control.UIAxes
        ax2                       matlab.ui.control.UIAxes
        ax1                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgV % Description
        img %temp image
        st %start Slice
        ed %end Slice
        cen % center on Axial
        cenSP %center of spinous process
        imgS %HF-set volume 
        imgS2 %virtual Xray image
        fnm %folderName on btn1 selected
        actDeg %actual degree
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btn1
        function btn1ButtonPushed(app, event)
            path=uigetdir(); %DICOM folder
            drawnow;
            figure(app.UIFigure);
            [~, app.fnm, ~] = fileparts(path);
            app.txt1.Text=path;
            f=dir(path);
            f=f(3:end);
            list={f.name};

            cen=floor(length(list)/2);

            cla(app.ax1,"reset");
            cla(app.ax2,"reset");
            cla(app.ax3,"reset");
            cla(app.ax4,"reset");

            h=waitbar(0,'Loading DICOM files...');

            for x=1:length(list)
                p=strcat(path,'\',list{x});
                img=dicomread(p);
                if x==1
                    sz=size(img);
                    imgV=zeros(sz(1),sz(2),length(list));
                end
                if app.cb2.Value==1
                    img =img+2000;
                    app.ed4.Value='2500';
                    app.ed5.Value='3300';
                else
                    app.ed4.Value='800';
                    app.ed5.Value='1400';
                end
                imgV(:,:,x)=img;
            end
            app.lst1.Items=list;
            if app.cb1.Value==1
                app.imgV=flip(imgV,3);
            else
                app.imgV=imgV;
            end
            app.img=imgV(:,:,cen);
            imshow(imgV(:,:,cen),'Parent',app.ax1,'DisplayRange',[]);
            close(h);

            app.sl1.Value=0;
            app.ed2.Value='0';
            app.sl2.Limits=[1,1000];
            app.sl2.Value=100;

            %btn2
            sz=size(app.imgV);
            if app.rb1.Value==1
                imgV3=imrotate(permute(app.imgV,[2 3 1]),-90);
                imshow(imgV3(:,:,floor(sz(1)*3/5)),'Parent',app.ax3,'DisplayRange',[]);
            else
                imgV3=imrotate(permute(app.imgV,[1 3 2]),-90);
                imshow(imgV3(:,:,sz(2)/2),'Parent',app.ax3,'DisplayRange',[]);
            end


        end

        % Value changed function: lst1
        function lst1ValueChanged(app, event)
            value = app.lst1.Value;
            path=strcat(app.txt1.Text,'\',value);
            app.img=dicomread(path);
            imshow(app.img,'Parent',app.ax1,'DisplayRange',[]);

        end

        % Value changed function: sl1
        function sl1ValueChanged(app, event)
            value = app.sl1.Value;
            ind=floor(value/5)*5;
            app.sl1.Value=ind;
            app.ed2.Value=num2str(ind);
            img=imrotate(app.img,-(ind-app.actDeg),'crop');
            imshow(img,'Parent',app.ax1,'DisplayRange',[]);
            sz=size(app.img);
            hold(app.ax1,"on");
            len=str2double(app.ed1.Value);
            plot(app.ax1,[1,sz(2)], [app.cen(2),app.cen(2)],'y-', 'LineWidth', 1)
            plot(app.ax1,[1,sz(2)], [app.cen(2)+len,app.cen(2)+len],'y:', 'LineWidth', 1)
            plot(app.ax1,[1,sz(2)], [app.cen(2)-len,app.cen(2)-len],'y:', 'LineWidth', 1)            
        end

        % Value changed function: sl2
        function sl2ValueChanged(app, event)
            value = app.sl2.Value;
            cen=app.cen;
            cen(2)=round(value);
            app.ed3.Value=num2str(cen(2));
            app.cen=cen;
            img=imrotate(app.img,-str2double(app.ed2.Value),'crop');
            imshow(img,'Parent',app.ax1,'DisplayRange',[]);
            sz=size(app.img);
            hold(app.ax1,"on");
            len=str2double(app.ed1.Value);
            plot(app.ax1,[1,sz(2)], [app.cen(2),app.cen(2)],'y-', 'LineWidth', 1)
            plot(app.ax1,[1,sz(2)], [app.cen(2)+len,app.cen(2)+len],'y:', 'LineWidth', 1)
            plot(app.ax1,[1,sz(2)], [app.cen(2)-len,app.cen(2)-len],'y:', 'LineWidth', 1) 

        end

        % Button pushed function: btn2
        function btn2ButtonPushed(app, event)
        
            sz=size(app.imgV);
            if app.rb1.Value==1
                imgV3=imrotate(permute(app.imgV,[2 3 1]),-90);
                imshow(imgV3(:,:,floor(sz(1)*3/5)),'Parent',app.ax3,'DisplayRange',[]);
            else
                imgV3=imrotate(permute(app.imgV,[1 3 2]),-90);
                imshow(imgV3(:,:,sz(2)/2),'Parent',app.ax3,'DisplayRange',[]);
            end

        end

        % Button pushed function: btn3
        function btn3ButtonPushed(app, event)
            pt1=drawpoint("Parent",app.ax3);
            app.st=round(pt1.Position(2));
            pt2=drawpoint("Parent",app.ax3);
            app.ed=round(pt2.Position(2));
            app.ed6.Value=num2str(app.ed-app.st);
            
        end

        % Button pushed function: btn4
        function btn4ButtonPushed(app, event)
            sz=size(app.img);
            imshow(app.img,'Parent',app.ax1,'DisplayRange',[]);
            pt1=drawpoint("Parent",app.ax1);
            app.cen=round(pt1.Position);
            pt2=drawpoint("Parent",app.ax1);
            app.cenSP=round(pt2.Position);

            slope = (app.cenSP(2) - app.cen(2)) / (app.cenSP(1) - app.cen(1));
            angle_rad = atan(slope);

            if rad2deg(angle_rad)<0
                degPre = -(90+rad2deg(angle_rad));
            else
                degPre = 90-rad2deg(angle_rad);
            end

            app.actDeg = degPre;
            app.ed9.Value=num2str(app.actDeg);

            cen1 = app.cen;
            cen2 = app.cenSP;

            % 直線の傾きと切片
            slope = (cen2(2) - cen1(2)) / (cen2(1) - cen1(1));
            y_intercept = cen1(2) - slope * cen1(1);

            % 直線が画像の範囲内にあるかどうかを判定する関数
            is_in_image = @(p) all(p >= 1) && all(p <= sz);

            % 2点を通る直線が画像のエッジにぶつかる2点を求める
            x1 = 1;
            y1 = slope * x1 + y_intercept;
            p1 = [x1, y1];
            if ~is_in_image(p1)
                y1 = 1;
                x1 = (y1 - y_intercept) / slope;
                p1 = [x1, y1];
            end

            x2 = sz(2);
            y2 = slope * x2 + y_intercept;
            p2 = [x2, y2];
            if ~is_in_image(p2)
                y2 = sz(1);
                x2 = (y2 - y_intercept) / slope;
                p2 = [x2, y2];
            end

            % 直線の式
            xp = [p1(1), p2(1)];
            yp = [p1(2), p2(2)];

            app.ed7.Value=num2str(app.cen(1));
            app.ed3.Value=num2str(app.cen(2));
            app.sl2.Value=app.cen(2);
            app.sl2.Limits=[app.cen(2)-50,app.cen(2)+50];

            hold(app.ax1,"on");
            len=str2double(app.ed1.Value);
            plot(app.ax1,[1,sz(2)], [app.cen(2),app.cen(2)],'y-', 'LineWidth', 1)
            plot(app.ax1,[1,sz(2)], [app.cen(2)+len,app.cen(2)+len],'y:', 'LineWidth', 1)
            plot(app.ax1,[1,sz(2)], [app.cen(2)-len,app.cen(2)-len],'y:', 'LineWidth', 1)

            plot(app.ax1,xp, yp,'g-', 'LineWidth', 2);

        end

        % Button pushed function: btn5
        function btn5ButtonPushed(app, event)
            if app.rb1.Value==1
                imgV0=app.imgV(:,:,app.st:app.ed);

                imgV=imrotate(imgV0,str2double(app.ed2.Value)-app.actDeg,'crop');
                imgV3=imrotate(permute(imgV,[2 3 1]),-90);
                app.imgS=imgV3;
                imgT=imgV3(:,:,app.cen(2));
                imshow(imgT,'Parent',app.ax2,'DisplayRange',[]);
                imgS=imgV3(:,:,app.cen(2)-str2double(app.ed1.Value):app.cen(2)+str2double(app.ed1.Value));
                imgS1=sum(imgS,3);
                imgS1=round(imgS1./(str2double(app.ed1.Value)*2));
                imgS1=imgS1+abs(min(imgS1(:)));
                app.imgS2=imgS1;
                %square Process
                sz=size(imgS1);
                deg=str2double(app.ed2.Value)/5*12; %adjust factor default>>12
                cen=str2double(app.ed7.Value)-deg;
                len=round(sz(1)/2);
                st=cen-len; %start px >>X
                if st<1
                    imgA=zeros(sz(1),abs(st));
                    imgN=horzcat(imgS1(:,1:(sz(1)+st)),imgA);

                else
                    if (sz(1)+st)>sz(2)
                        imgA=zeros(sz(1),sz(1)+st-sz(2)-1);
                        imgN=horzcat(imgA,imgS1(:,st:sz(2)));
                    else
                        imgN=imgS1(:,st:(st+sz(1)-1));
                    end
                end
                imshow(imgN,'Parent',app.ax4,'DisplayRange',[str2double(app.ed4.Value) str2double(app.ed5.Value)]);
            else
                imgV3=imrotate(permute(app.imgV,[1 3 2]),-90);
                imshow(imgV3(app.st:app.ed,:,appcen),'Parent',app.ax2,'DisplayRange',[]);

            end
           
        end

        % Button pushed function: btn6
        function btn6ButtonPushed(app, event)
             img=app.imgS2;
             sz=size(img);
             deg=str2double(app.ed2.Value)/5*3;
             cen=str2double(app.ed7.Value)-deg;
             len=round(sz(1)/2);
             st=cen-len; %start px >>X
             imgN=img(:,st:(st+len*2+1));
             imshow(imgN,'Parent',app.ax4,'DisplayRange',[str2double(app.ed4.Value) str2double(app.ed5.Value)]);  
        end

        % Button pushed function: btn7
        function btn7ButtonPushed(app, event)
            path=uigetdir();
            drawnow;
            figure(app.UIFigure);
            app.ed8.Value=strcat(path,'\');
        end

        % Button pushed function: btn8
        function btn8ButtonPushed(app, event)
             
            imgV0=app.imgV(:,:,app.st:app.ed);
            h=waitbar(0,'Save as JPEG..');
            for deg=-60:5:60
                ddd=deg+app.actDeg
                imgV=imrotate(imgV0,(deg-app.actDeg),'crop');
                imgV3=imrotate(permute(imgV,[2 3 1]),-90);
                imgS=imgV3(:,:,app.cen(2)-str2double(app.ed1.Value):app.cen(2)+str2double(app.ed1.Value));
                imgS1=sum(imgS,3);
                imgS1=round(imgS1./(str2double(app.ed1.Value)*2));
                imgS1=imgS1+abs(min(imgS1(:)));


                %square Process
                sz=size(imgS1);
                if deg<0
                    nm=strcat('p',num2str(abs(deg),'%02i'));
                elseif deg==0
                    waitbar(0.5,h,'Save as JPEG..');
                    nm=strcat('p',num2str(abs(deg),'%02i'));
                else
                    nm=strcat('n',num2str(abs(deg),'%02i'));
                end
                dpath=strcat(app.ed8.Value,app.fnm,'_',nm,'.JPG');


                deg2=deg/5*12; %adjust factor default>>12
                cen=str2double(app.ed7.Value)-deg2;
                len=round(sz(1)/2);
                st=cen-len; %start px >>X
                if st<1
                    imgA=zeros(sz(1),abs(st));
                    imgN=horzcat(imgS1(:,1:(sz(1)+st)),imgA);

                else
                    if (sz(1)+st)>sz(2)
                        imgA=zeros(sz(1),sz(1)+st-sz(2)-1);
                        imgN=horzcat(imgA,imgS1(:,st:sz(2)));
                    else
                        imgN=imgS1(:,st:(st+sz(1)-1));
                    end
                end
                lowV=str2double(app.ed4.Value);
                highV=str2double(app.ed5.Value);
                imgN(imgN<lowV)=0;
                imgN(imgN>highV)=highV;
                imgN2=imgN-lowV;
                imgN2(imgN2<0)=0;
                imgN3=imgN2./(highV-lowV);
                imgN4=imgN3.*255;
                imgN4=uint8(imgN4);
                imgN5=cat(3,imgN4,imgN4,imgN4);
                imwrite(imgN5,dpath);
            end
            close(h);
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax1 = uiaxes(app.UIFigure);
            app.ax2 = uiaxes(app.UIFigure);
            app.ax3 = uiaxes(app.UIFigure);
            app.ax4 = uiaxes(app.UIFigure);
            app.btn1 = uibutton(app.UIFigure, 'push');
            app.btn1.ButtonPushedFcn = createCallbackFcn(app, @btn1ButtonPushed, true);
            app.btn1.Text = 'Load DICOM folder[1]';
            app.txt1 = uilabel(app.UIFigure);
            app.bg = uibuttongroup(app.UIFigure);
            app.bg.Title = 'View plane';
            app.rb1 = uiradiobutton(app.bg);
            app.rb1.Text = 'Coronal';
            app.rb1.Value = true;
            app.rb2 = uiradiobutton(app.bg);
            app.rb2.Text = 'Sagittal';
            app.lst1 = uilistbox(app.UIFigure);
            app.lst1.Items = {'lst1'};
            app.lst1.ValueChangedFcn = createCallbackFcn(app, @lst1ValueChanged, true);
            app.lst1.Value = {};
            app.rotdegLabel = uilabel(app.UIFigure);
            app.rotdegLabel.Text = 'rot-deg';
            app.sl1 = uislider(app.UIFigure);
            app.sl1.Limits = [-60 60];
            app.sl1.MajorTicks = [-60 -45 -30 -15 0 15 30 45 60];
            app.sl1.MajorTickLabels = {'-60', '-45', '-30', '-15', '0', '15', '30', '45', '60'};
            app.sl1.ValueChangedFcn = createCallbackFcn(app, @sl1ValueChanged, true);
            app.sl1.MinorTicks = [-60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60];
            app.centerYLabel_2 = uilabel(app.UIFigure);
            app.centerYLabel_2.Text = 'centerY';
            app.sl2 = uislider(app.UIFigure);
            app.sl2.Limits = [1 501];
            app.sl2.ValueChangedFcn = createCallbackFcn(app, @sl2ValueChanged, true);
            app.sl2.Value = 1;
            app.btn2 = uibutton(app.UIFigure, 'push');
            app.btn2.ButtonPushedFcn = createCallbackFcn(app, @btn2ButtonPushed, true);
            app.btn2.Enable = 'off';
            app.btn2.Text = 'View plane[2]';
            app.btn3 = uibutton(app.UIFigure, 'push');
            app.btn3.ButtonPushedFcn = createCallbackFcn(app, @btn3ButtonPushed, true);
            app.btn3.Text = 'Set start-end[3]';
            app.btn4 = uibutton(app.UIFigure, 'push');
            app.btn4.ButtonPushedFcn = createCallbackFcn(app, @btn4ButtonPushed, true);
            app.btn4.Text = 'Set center [4]';
            app.rangeEditFieldLabel = uilabel(app.UIFigure);
            app.rangeEditFieldLabel.Text = 'range';
            app.ed1 = uieditfield(app.UIFigure, 'text');
            app.ed1.Value = '80';
            app.btn5 = uibutton(app.UIFigure, 'push');
            app.btn5.ButtonPushedFcn = createCallbackFcn(app, @btn5ButtonPushed, true);
            app.btn5.Text = 'Create vXray[5]';
            app.cb1 = uicheckbox(app.UIFigure);
            app.cb1.Text = 'Slice order F>>H';
            app.cb1.Value = true;
            app.degEditFieldLabel = uilabel(app.UIFigure);
            app.degEditFieldLabel.Enable = 'off';
            app.degEditFieldLabel.Text = 'deg';
            app.ed2 = uieditfield(app.UIFigure, 'text');
            app.ed2.Enable = 'off';
            app.ed2.Value = '0';
            app.centerYLabel = uilabel(app.UIFigure);
            app.centerYLabel.Enable = 'off';
            app.centerYLabel.Text = 'centerY';
            app.ed3 = uieditfield(app.UIFigure, 'text');
            app.ed3.Enable = 'off';
            app.ed3.Value = '0';
            app.btn6 = uibutton(app.UIFigure, 'push');
            app.btn6.ButtonPushedFcn = createCallbackFcn(app, @btn6ButtonPushed, true);
            app.btn6.Text = 'Preview [6]';
            app.minEditFieldLabel = uilabel(app.UIFigure);
            app.minEditFieldLabel.Text = 'min';
            app.ed4 = uieditfield(app.UIFigure, 'text');
            app.ed4.Value = '800';
            app.maxEditFieldLabel = uilabel(app.UIFigure);
            app.maxEditFieldLabel.Text = 'max';
            app.ed5 = uieditfield(app.UIFigure, 'text');
            app.ed5.Value = '1400';
            app.lengthLabel = uilabel(app.UIFigure);
            app.lengthLabel.Enable = 'off';
            app.lengthLabel.Text = 'length';
            app.ed6 = uieditfield(app.UIFigure, 'text');
            app.ed6.Enable = 'off';
            app.ed6.Value = '0';
            app.centerYLabel_3 = uilabel(app.UIFigure);
            app.centerYLabel_3.Enable = 'off';
            app.centerYLabel_3.Text = 'centerX';
            app.ed7 = uieditfield(app.UIFigure, 'text');
            app.ed7.Enable = 'off';
            app.ed7.Value = '0';
            app.SavefolderEditFieldLabel = uilabel(app.UIFigure);
            app.SavefolderEditFieldLabel.Text = 'Save folder';
            app.ed8 = uieditfield(app.UIFigure, 'text');
            app.btn7 = uibutton(app.UIFigure, 'push');
            app.btn7.ButtonPushedFcn = createCallbackFcn(app, @btn7ButtonPushed, true);
            app.btn7.Text = 'Set saveFolder [7]';
            app.btn8 = uibutton(app.UIFigure, 'push');
            app.btn8.ButtonPushedFcn = createCallbackFcn(app, @btn8ButtonPushed, true);
            app.btn8.Text = 'SaveRotImgaes [8]';
            app.actualdegLabel = uilabel(app.UIFigure);
            app.actualdegLabel.Enable = 'off';
            app.actualdegLabel.Text = 'actual deg';
            app.ed9 = uieditfield(app.UIFigure, 'text');
            app.ed9.Enable = 'off';
            app.ed9.Value = '0';
            app.cb2 = uicheckbox(app.UIFigure);
            app.cb2.Text = '+2000';
            app.cb2.Value = true;
            app.cb3 = uicheckbox(app.UIFigure);
            app.cb3.Text = 'trueSUM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = create_vXray

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end